classdef FruitLoop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        SIMULATECOWButton          matlab.ui.control.Button
        RESUMEButton               matlab.ui.control.Button
        STOPButton                 matlab.ui.control.Button
        Image2                     matlab.ui.control.Image
        OpenLabel                  matlab.ui.control.Label
        CloseLabel                 matlab.ui.control.Label
        RESETButton                matlab.ui.control.StateButton
        SystemStatus               matlab.ui.control.Label
        FruitLoopStatusLabel       matlab.ui.control.Label
        HarvestOverviewLabel       matlab.ui.control.Label
        LightCurtainSwitch         matlab.ui.control.Switch
        LightCurtainSwitchLabel    matlab.ui.control.Label
        ZLabel                     matlab.ui.control.Label
        EndEffZ                    matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        EndEffY                    matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        EndEffX                    matlab.ui.control.Label
        RipeFruitLabel             matlab.ui.control.Label
        ripeFruitDisplay           matlab.ui.control.Label
        FruitsortedLabel           matlab.ui.control.Label
        sortCountDisplay           matlab.ui.control.Label
        FruitpickedLabel           matlab.ui.control.Label
        fruitCountDisplay          matlab.ui.control.Label
        SafetySwitch               matlab.ui.control.ToggleSwitch
        SafetySwitchLabel          matlab.ui.control.Label
        CollisionWarningLamp       matlab.ui.control.Lamp
        CollisionWarningLampLabel  matlab.ui.control.Label
        EndEffPositionLabel        matlab.ui.control.Label
        FruitLoopVirtualViewLabel  matlab.ui.control.Label
        ManualModeSwitch           matlab.ui.control.Switch
        ManualModeSwitchLabel      matlab.ui.control.Label
        RobotToggleSwitch          matlab.ui.control.Switch
        RobotToggleSwitchLabel     matlab.ui.control.Label
        GripperLabel               matlab.ui.control.Label
        RobotLinksLabel            matlab.ui.control.Label
        g1Slider                   matlab.ui.control.Slider
        q7Slider                   matlab.ui.control.Slider
        q7SliderLabel              matlab.ui.control.Label
        q6Slider                   matlab.ui.control.Slider
        q6SliderLabel              matlab.ui.control.Label
        q4Slider                   matlab.ui.control.Slider
        q4SliderLabel              matlab.ui.control.Label
        q5Slider                   matlab.ui.control.Slider
        q5SliderLabel              matlab.ui.control.Label
        q3Slider                   matlab.ui.control.Slider
        q3SliderLabel              matlab.ui.control.Label
        q1Slider                   matlab.ui.control.Slider
        q1SliderLabel              matlab.ui.control.Label
        q2Slider                   matlab.ui.control.Slider
        q2SliderLabel              matlab.ui.control.Label
        HumidityGauge              matlab.ui.control.SemicircularGauge
        HumidityGaugeLabel         matlab.ui.control.Label
        TemperatureCGauge          matlab.ui.control.SemicircularGauge
        TemperatureCGaugeLabel     matlab.ui.control.Label
        HarvestQAButton            matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        QAOnlyButton               matlab.ui.control.Button
        HarvestOnlyButton          matlab.ui.control.Button
        plot                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here

        %% Robot Properties
        modelLinearUR3 % Linear UR3 to be loaded
        modelPanda % Panda to be loaded
        cow

        %% Gui
        fruitPicked
        fruitSorted
        fruitRipe
        manualMode

        %% Gripper Properties
        % LinearUR3 Gripper
        g1
        g2

        % Panda Gripper
        g3
        g4

        pandaGripSliderValue
        UR3GripSliderValue

       %% eStop
         % estop
          safety
          automode
          teachresume
        
          %last for Panda value.
          lastQPanda
          lastQUR3
        %% Initialise Class instances
        FruitSys % Fruit System Class
        objFunc % Object Function Class
        % robotFunc % Robot Function Class

        %% Fruit Properties
        tree1_pos
        tree1_obj
        tree1_verts
        tree1_picked
        tree2_pos
        tree2_obj
        tree2_verts
        tree2_picked
        tree1_crate_pos
        tree2_crate_pos
        tree1_above_crate
        tree2_above_crate
        tree1_sorted_crate_pos
        tree2_sorted_crate_pos
        tree1_above_sorted_crate
        tree2_above_sorted_crate

        treeCount
        sortCount
    end
    
    properties (Access = public)
        
        %% eStop and Manual Mode used in Robot Functions Class
        
        estop
        manual
        resume

        % moved robotFunc to public for estop
        robotFunc


    end

    
    methods (Access = private)
        % Animation for manual mode sliders.
        function manualAnimation(app,robot,newQ)
            % Animate robot
            animate(robot.model,newQ);
            % Animate gripper on robot depending on robot chosen
            
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                % Gripper base transform for LinearUR3.
                pos1 = robot.model.fkineUTS(robot.model.getpos())*transl(0,-0.0127,0.0612)*troty(-pi/2);%z0.0612
                pos2 = robot.model.fkineUTS(robot.model.getpos())*transl(0,0.0127,0.0612)*troty(-pi/2);%z0.0612
                app.g1.model.base = pos1; 
                app.g2.model.base = pos2; 
                app.g1.model.animate(app.g1.model.getpos());
                app.g2.model.animate(app.g2.model.getpos());
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                % Gripper base transform for Panda.
                pos3 = robot.model.fkineUTS(robot.model.getpos())*transl(0,-0.0127,0.05)*troty(-pi/2);%z0.0612
                pos4 = robot.model.fkineUTS(robot.model.getpos())*transl(0,0.0127,0.05)*troty(-pi/2);%z0.0612
                app.g3.model.base = pos3; 
                app.g4.model.base = pos4; 
                app.g3.model.animate(app.g3.model.getpos());
                app.g4.model.animate(app.g4.model.getpos());
            end
        end

        function gripperAnimation(app,angle)
            % Produce link vector.
                leftQ = [deg2rad(-angle),deg2rad(angle),0];
                rightQ = [deg2rad(angle),deg2rad(-angle),0];
            % Animate gripper depending on robot.
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                % animation for LinearUR3 gripper.
                app.g1.model.animate(leftQ);
                app.g2.model.animate(rightQ);
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                % animation for Panda gripper.
                app.g3.model.animate(leftQ);
                app.g4.model.animate(rightQ);
            end
        end

        %% Movement back to previous Q if teach is used mid animation
        function manualTwoRobotAnimation(app,robot,robot2) % robot = UR3 % robot2 = Panda
            % Animate robot

           ReturnSteps = 30;
          
           %% 
        % app.lastQPanda = app.modelpanda.model.getpos();
        % app.lastQUR3 = app.modelUR3.model.getpos();

        currentQPanda = app.modelPanda.model.getpos();
        currentQUR3 = app.modelLinearUR3.model.getpos();

        OldUR3pos = app.lastQUR3;
        OldPandapos = app.lastQPanda;

                    ReturnqPath1 = jtraj(currentQUR3,OldUR3pos,ReturnSteps);
                    ReturnqPath2 = jtraj(currentQPanda,OldPandapos,ReturnSteps);


            for i = 1:ReturnSteps

                robot.model.animate(ReturnqPath1(i,:));
                robot2.model.animate(ReturnqPath2(i,:));
            % animate(robot.model,newQ1);
            % animate(robot2.model,newQ2)

                % Animate grippers on robots 
            
                % Gripper base transform for LinearUR3.
                pos1 = robot.model.fkineUTS(robot.model.getpos())*transl(0,-0.0127,0.0612)*troty(-pi/2);%z0.0612
                pos2 = robot.model.fkineUTS(robot.model.getpos())*transl(0,0.0127,0.0612)*troty(-pi/2);%z0.0612
                app.g1.model.base = pos1; 
                app.g2.model.base = pos2; 
                app.g1.model.animate(app.g1.model.getpos());
                app.g2.model.animate(app.g2.model.getpos());
                
                pos3 = robot2.model.fkineUTS(robot2.model.getpos())*transl(0,-0.0127,0.05)*troty(-pi/2);%z0.0612
                pos4 = robot2.model.fkineUTS(robot2.model.getpos())*transl(0,0.0127,0.05)*troty(-pi/2);%z0.0612
                app.g3.model.base = pos3; 
                app.g4.model.base = pos4; 
                app.g3.model.animate(app.g3.model.getpos());
                app.g4.model.animate(app.g4.model.getpos());

                drawnow();

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            %% Clean Terminal
            clc;
            
            %% Button status
            app.HarvestOnlyButton.Enable = false;
            app.QAOnlyButton.Enable = false;
            app.STOPButton.Enable = false;
            app.HarvestQAButton.Enable = false;
            app.RESUMEButton.Enable = false;
            app.LightCurtainSwitch.Enable = false;
            app.SafetySwitch.Enable = false;
            app.RESETButton.Enable = false;
            app.ManualModeSwitch.Enable = false;
            app.RobotToggleSwitch.Enable = false;
            app.q1Slider.Enable = false;
            app.q2Slider.Enable = false;
            app.q3Slider.Enable = false;
            app.q4Slider.Enable = false;
            app.q5Slider.Enable = false;
            app.q6Slider.Enable = false;
            app.q7Slider.Enable = false;
            app.g1Slider.Enable = false;
            app.SIMULATECOWButton.Enable = false;

            %% Initialise first robot (LinearUR3) and plot
            % Create a blank 3D plot in the UIAxes component
            plot3(app.plot, [], [], []);
            % Set the axis limits
            axis(app.plot, [-2.3 1.5 -2.3 1.2 0.01 1.3]);
            hold on;

            %% Initialise instance of classes.
            app.objFunc = ObjectFunctions();
            app.FruitSys = FruitLoopSystem();
            app.robotFunc = RobotFunctions(app); %added app in attempt to pass estop through
            %% Set up static environment.
            app.FruitSys.Environment();
            % Set Mandarin locations and initialise them.
            [app.tree1_pos, app.tree1_obj, app.tree1_verts, app.tree1_picked, app.tree2_pos, app.tree2_obj, app.tree2_verts, app.tree2_picked, app.tree1_crate_pos, app.tree2_crate_pos, app.tree1_above_crate, app.tree2_above_crate, app.tree1_sorted_crate_pos, app.tree2_sorted_crate_pos, app.tree1_above_sorted_crate, app.tree2_above_sorted_crate] = app.FruitSys.Mandarins();
            
            %% Initialise first robot (LinearUR3)
            % Robot
            app.modelLinearUR3 = LinearUR3(transl(0,0,0.02));
            % Gripper
            pos1 = (app.modelLinearUR3.model.fkineUTS(app.modelLinearUR3.model.getpos()))*transl(0,0.0127,0.0612)*trotx(pi/2); % Base position right gripper offset from UR3's end effector (0.0127 is the ditance of the grip from the base cebtre and 0.0612 is the depth of the base)
            pos2 = (app.modelLinearUR3.model.fkineUTS(app.modelLinearUR3.model.getpos()))*transl(0,-0.0127,0.0612)*trotx(pi/2); % Base position left gripper offset from UR3's end effector (-0.0127 is the ditance of the grip from the base cebtre and 0.0612 is the depth of the base)
            app.g1 = GripRight(pos1); % initiate right gripper
            app.g2 = GripLeft(pos2); % initial left gripper
            app.robotFunc.GripperMove(app.g1,app.g2,1); % Close Gripper to operating distance for Mandarin (open close 10 degrees)

            %% Initialise second robot (Panda)
            % Robot
            app.modelPanda = Panda(transl(-0.4,-1,0.02));
            % Gripper
            pos3 = (app.modelPanda.model.fkineUTS(app.modelPanda.model.getpos()))* transl(0,-0.0127,0.05)*trotx(-pi/2)*trotz(pi);  % Base position right gripper offset from Panda's end effector 
            pos4 = (app.modelPanda.model.fkineUTS(app.modelPanda.model.getpos()))* transl(0,0.0127,0.05)*trotx(-pi/2)*trotz(pi); % Base position left gripper offset from Panda's end effector 
            app.g3 = GripRight(pos3); % initiate right gripper
            app.g4 = GripLeft(pos4); % initial left gripper 
            app.robotFunc.GripperMove(app.g3,app.g4,1); % Close Gripper to operating distance for Mandarin (open close 10 degrees)

            % Reset counts
            app.fruitSorted = 0;
            app.fruitPicked = 0;
            app.fruitRipe = 0;

            % Set count to 1 for first tree - normal
            app.treeCount = 1;
            app.sortCount = 1;

            %Auto mode not engaged at start/ initial teachresume bool as 0
            app.automode = 0;
            app.teachresume = 0;

            % Set count to 2 for second tree - testing
            % app.treeCount = 2;
            % app.sortCount = 2;

            app.SystemStatus.Text = 'Ready for operation.';
            app.SystemStatus.FontColor = '#276221';

            %% Cow
            app.cow = BabyCow(transl(2,0,0.01));

            %% Button settings.
            app.HarvestQAButton.Enable = true;
            app.HarvestOnlyButton.Enable = true;
            app.ManualModeSwitch.Enable = true;
            app.LightCurtainSwitch.Enable = true;
            app.RESETButton.Enable = true;
            app.SIMULATECOWButton.Enable = true;

            % Update link limits
            app.q1Slider.Limits = [-0.8 -0.01];
            app.q2Slider.Limits = [-360 360]*pi/180;
            app.q3Slider.Limits = [-90 90]*pi/180;
            app.q4Slider.Limits = [-170 170]*pi/180;
            app.q5Slider.Limits = [-360 360]*pi/180;
            app.q6Slider.Limits = [-360 360]*pi/180;
            app.q7Slider.Limits = [-360 360]*pi/180;
            app.g1Slider.Limits = [-40, 20];
            % .Limits = [-pi, pi];

        end

        % Button pushed function: HarvestOnlyButton
        function HarvestOnlyButtonPushed(app, event)
            %% Enable Buttons
            app.HarvestOnlyButton.Enable = false;
            app.HarvestQAButton.Enable = false;
            app.STOPButton.Enable = true;
            app.estop;
            app.automode = 1;
            %% Harvest Tree 1
            if app.treeCount == 1
                display(['Tree 1 Harvest: Beginning picking process.']);
                app.SystemStatus.Text = 'Harvesting tree 1.';
                app.SystemStatus.FontColor = '#FFA500';
                for x = 1:size(app.tree1_pos, 1)
                    % Look for Mandarin
                    display(['Tree 1 Harvest: Look for Mandarin ', num2str(x)]);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.45,app.tree1_pos(x,3)],50,0,false,0,1,app.g1,app.g2,0,app.cow);
                    
                    % Move to initial Mandarin location
                    display(['Tree 1 Harvest: Go to Mandarin ', num2str(x)]);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.2,app.tree1_pos(x,3)],30,0,false,0,1,app.g1,app.g2,1,app.cow);
                
                    % Move Mandarins away from tree
                    display(['Tree 1 Harvest: Pick Mandarin ', num2str(x), ' from tree.']);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.45,app.tree1_pos(x,3)],30,app.tree1_obj{x},true,app.tree1_verts{x},1,app.g1,app.g2,0,app.cow);
                
                    % Move Mandarins above crate
                    display(['Tree 1 Harvest: Place Mandarin ', num2str(x), ' above crate.']);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.7],30,app.tree1_obj{x},true,app.tree1_verts{x},3,app.g1,app.g2,0,app.cow);
                 
                    % Place Mandarins in crate
                    display(['Tree 1 Harvest: Place Mandarin ', num2str(x), ' within the crate.']);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.2],30,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g1,app.g2,0,app.cow);
                
                    % Lift End Effector from crate
                    display(['Tree 1 Harvest: Lift gripper ', num2str(x), ' from the crate.']);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.6],30,0,false,0,2,app.g1,app.g2,2,app.cow);
                
                    % Face left
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[-0.6,0,0.5],30,0,false,0,0,app.g1,app.g2,2,app.cow);
    
                    % Count Mandarin picked.
                    app.fruitPicked = app.fruitPicked + 1;
                    app.fruitCountDisplay.Text = num2str(app.fruitPicked);
                    display(['Tree 1 Harvest: The total number of Mandarins picked from tree 1 is ', num2str(x)]);
                end
                
                display(['Tree 1 Harvest: Completed.']);
                display(['=====================================']);
                
                % Add to tree counter
                app.treeCount = app.treeCount + 1;

                % Once harvest is complete, we can start sorting.
                app.QAOnlyButton.Enable = true;
                app.HarvestOnlyButton.Enable = true;
                app.HarvestQAButton.Enable = true;

                % % If we have fruit to pick and fruits to sort in tree 2
                % if app.treeCount == 2 & app.sortCount == 2
                %     app.HarvestQAButton.Enable = true;
                % end

            elseif app.treeCount == 2
                %% Harvest Tree 2
                app.SystemStatus.Text = 'Harvesting tree 2.';
                app.SystemStatus.FontColor = '#FFA500';
                display(['Tree 2 Harvest: Beginning picking process.']);                
                for j = 1:size(app.tree2_pos, 1)
                    % Look for Mandarin
                    display(['Tree 2 Harvest: Look for Mandarin ', num2str(x)]);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_pos(x,1),app.tree2_pos(x,2)-0.45,app.tree2_pos(x,3)],50,0,false,0,1,app.g1,app.g2,0,app.cow);
                
                    % Move to initial Mandarin location
                    display(['Tree 2 Harvest: Go to Mandarin ', num2str(j)]);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_pos(j,1),app.tree2_pos(j,2)-0.2,app.tree2_pos(j,3)],30,0,false,0,1,app.g1,app.g2,1,app.cow);
                
                    % Move Mandarins away from tree
                    display(['Tree 2 Harvest: Pick Mandarin ', num2str(j), ' from tree.']);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_picked(j,1),app.tree2_picked(j,2)-0.45,app.tree2_picked(j,3)],30,app.tree2_obj{j},true,app.tree2_verts{j},1,app.g1,app.g2,0,app.cow);
                
                    % Move Mandarins to crate
                    display(['Tree 2 Harvest: Place Mandarin ', num2str(j), ' above crate.']);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_above_crate(j,1),app.tree2_above_crate(j,2),app.tree2_above_crate(j,3)+0.7],30,app.tree2_obj{j},true,app.tree2_verts{j},2,app.g1,app.g2,3,app.cow);
                
                    % Place Mandarins in crate
                    display(['Tree 2 Harvest: Place Mandarin ', num2str(j), ' within the crate.']);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_crate_pos(j,1),app.tree2_crate_pos(j,2),app.tree2_crate_pos(j,3)+0.2],30,app.tree2_obj{j},true,app.tree2_verts{j},2,app.g1,app.g2,0,app.cow);
                
                    % Lift End Effector from crate
                    display(['Tree 2 Harvest: Lift the gripper ', num2str(j), ' from the crate.']);
                    app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_above_crate(j,1),app.tree2_above_crate(j,2),app.tree2_above_crate(j,3)+0.7],30,0,false,0,2,app.g1,app.g2,2,app.cow);
                
                    % Count Mandarin picked.
                    app.fruitPicked = app.fruitPicked + 1;
                    app.fruitCountDisplay.Text = num2str(app.fruitPicked);
                end
                
                % Return Robot to beginning.
                app.robotFunc.MoveRobot(app.modelLinearUR3,[-0.2, 0, 0.65],50,0,false,0,3,app.g1,app.g2,1,app.cow);
                
                display(['Tree 2 Harvest: Completed.']);
                display(['=====================================']);
                 % Add to tree counter
                app.treeCount = app.treeCount + 1;

                % Once harvest is complete, we can start sorting.
                app.QAOnlyButton.Enable = true;
                app.HarvestOnlyButton.Enable = true;
                app.HarvestQAButton.Enable = true;
            else
                app.SystemStatus.Text = 'No more trees to harvest.';
                app.SystemStatus.FontColor = 'black';
                display(['NO MORE TREES TO HARVEST']);
                % Once harvest is complete, we can start sorting.
                app.HarvestQAButton.Enable = false;
                app.HarvestOnlyButton.Enable = false;
            end
            app.automode = 0;
        end

        % Button pushed function: QAOnlyButton
        function QAOnlyButtonPushed(app, event)
             %% Conduct Quality Control on set 1
             app.automode = 1;

            display(['Quality Control S1: Begin Filtering.']);
            if app.sortCount == 1
                app.SystemStatus.Text = 'Sorting fruit from Tree 1.';
                app.SystemStatus.FontColor = 'yellow';
                for x = 1:size(app.tree1_sorted_crate_pos, 1)
                    % Look for Mandarin in unsorted crate
                    display(['Quality Control S1: Look for Mandarins to filter ', num2str(x)]);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_crate(x,1),app.tree1_above_crate(x,2),app.tree1_above_crate(x,3)],50,0,false,0,2,app.g3,app.g4,0,app.cow);
                    
                    % Go to mandarin in unsorted crate and grip
                    display(['Quality Control S1: Go to Mandarin ', num2str(x)]);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.2],50,0,false,0,2,app.g3,app.g4,1,app.cow);
                    
                    % Pick up mandarin from unsorted crate
                    display(['Quality Control S1: Pick Mandarin ', num2str(x), ' from tree.']);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_crate(x,1),app.tree1_above_crate(x,2),app.tree1_above_crate(x,3)+0.2],50,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g3,app.g4,0,app.cow);
                
                    % Move mandarin above sorted crate
                    display(['Quality Control S1: Place Mandarin ', num2str(x), ' above crate.']);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_sorted_crate(x,1),app.tree1_above_sorted_crate(x,2),app.tree1_above_sorted_crate(x,3)+0.5],50,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g3,app.g4,0,app.cow);
                 
                    % Place Mandarins in sorted crate
                    display(['Quality Control S1: Place Mandarin ', num2str(x), ' within the crate.']);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_sorted_crate_pos(x,1),app.tree1_sorted_crate_pos(x,2),app.tree1_sorted_crate_pos(x,3)+0.2],50,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g3,app.g4,2,app.cow);
                
                    % Release mandarin and lift away from sorted crate
                    display(['Quality Control S1: Lift gripper ', num2str(x), ' from the crate.']);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_sorted_crate(x,1),app.tree1_above_sorted_crate(x,2),app.tree1_above_sorted_crate(x,3)+0.5],50,0,false,0,2,app.g3,app.g4,0,app.cow);
                
                    % Count Mandarin picked.
                    display(['Quality Control S1: The total number of Mandarins picked from tree 1 is ', num2str(x)]);
                    app.fruitSorted = app.fruitSorted + 1;
                    app.sortCountDisplay.Text = num2str(app.fruitSorted);

                    if x < size(app.tree1_sorted_crate_pos, 1)
                        app.fruitRipe = app.fruitRipe + 1;
                        app.ripeFruitDisplay.Text = num2str(app.fruitRipe);
                    end
                    
                end
            
                display(['Quality Control S1: Completed Filtering.']);
                display(['=====================================']);
    
                app.sortCount = app.sortCount + 1;
                app.QAOnlyButton.Enable = true;
                app.HarvestQAButton.Enable = true;
                app.HarvestOnlyButton.Enable = true;
            elseif app.sortCount == 2
                 %% Conduct Quality Control on set 2
                display(['Quality Control S2: Begin Filtering.']);
                app.SystemStatus.Text = 'Sorting fruit from tree 2.';
                app.SystemStatus.FontColor = 'yellow';
                for x = 1:size(app.tree1_sorted_crate_pos, 1)
                    % Look for Mandarin in unsorted crate
                    display(['Quality Control S1: Look for Mandarins to filter ', num2str(x)]);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_crate(x,1),app.tree2_above_crate(x,2),app.tree2_above_crate(x,3)],25,0,false,0,2,app.g3,app.g4,0,app.cow);
                    
                    % Go to mandarin in unsorted crate and grip
                    display(['Quality Control S1: Go to Mandarin ', num2str(x)]);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_crate_pos(x,1),app.tree2_crate_pos(x,2),app.tree2_crate_pos(x,3)+0.2],30,0,false,0,2,app.g3,app.g4,1,app.cow);
                    
                    % Pick up mandarin from unsorted crate
                    display(['Quality Control S1: Pick Mandarin ', num2str(x), ' from tree.']);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_crate(x,1),app.tree2_above_crate(x,2),app.tree2_above_crate(x,3)+0.2],40,app.tree2_obj{x},true,app.tree2_verts{x},2,app.g3,app.g4,0,app.cow);
                
                    % Move mandarin above sorted crate
                    display(['Quality Control S1: Place Mandarin ', num2str(x), ' above crate.']);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_sorted_crate(x,1),app.tree2_above_sorted_crate(x,2),app.tree2_above_sorted_crate(x,3)+0.5],40,app.tree2_obj{x},true,app.tree2_verts{x},2,app.g3,app.g4,0,app.cow);
                 
                    % Place Mandarins in sorted crate
                    display(['Quality Control S1: Place Mandarin ', num2str(x), ' within the crate.']);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_sorted_crate_pos(x,1),app.tree2_sorted_crate_pos(x,2),app.tree2_sorted_crate_pos(x,3)+0.2],40,app.tree2_obj{x},true,app.tree2_verts{x},2,app.g3,app.g4,2,app.cow);
                
                    % Release mandarin and lift away from sorted crate
                    display(['Quality Control S1: Lift gripper ', num2str(x), ' from the crate.']);
                    app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_sorted_crate(x,1),app.tree2_above_sorted_crate(x,2),app.tree2_above_sorted_crate(x,3)+0.5],40,0,false,0,2,app.g3,app.g4,0,app.cow);
                
                    % Count Mandarin picked.
                    display(['Quality Control S1: The total number of Mandarins picked from tree 1 is ', num2str(x)]);
                    app.fruitSorted = app.fruitSorted + 1;
                    app.sortCountDisplay.Text = num2str(app.fruitSorted);

                    if x < size(app.tree1_sorted_crate_pos, 1)
                        app.fruitRipe = app.fruitRipe + 1;
                        app.ripeFruitDisplay.Text = num2str(app.fruitRipe);
                    end

                end
                % Return Robot to beginning.
                app.robotFunc.MoveRobot(app.modelPanda,[-1.2,-0.7,1.3],40,0,false,0,0,app.g3,app.g4,2,app.cow);

                app.sortCount = app.sortCount + 1;

                app.QAOnlyButton.Enable = true;
                app.HarvestQAButton.Enable = true;
                app.HarvestOnlyButton.Enable = true;
                display(['Quality Control S2: Completed Filtering.']);
                display(['=====================================']);
            else
                display(['NO MORE FRUIT TO SORT.']);
                app.SystemStatus.Text = 'No more fruit to sort.';
                app.SystemStatus.FontColor = 'black';
                app.QAOnlyButton.Enable = false;
            end
            app.automode = 0;
           
        end

        % Callback function
        function FruitpickedEditFieldValueChanged(app, event)
            % app.FruitpickedEditField.Value = num2str(app.fruitPicked);
            % drawnow;
            % display(['FRUIT PICKED COUNTER CALLBACK CALLED.'])

        end

        % Callback function
        function FruitsortedEditFieldValueChanged(app, event)
           app.FruitpickedEditField.Value = app.fruitSorted;
        end

        % Button pushed function: HarvestQAButton
        function HarvestQAButtonPushed(app, event)
            %% Harvest and QA at the same time.
            %% Enable buttons
            app.QAOnlyButton.Enable = false;
            app.HarvestOnlyButton.Enable = false;
            app.HarvestQAButton.Enable = false;
            app.STOPButton.Enable = true;
            app.estop;
            offset = 1; % Number of fruit to be picked before QA bot can begin sorting.
            app.automode = 1;

            %% Harvest and QA for Tree 1.
            if app.treeCount == 1
               if app.sortCount == 1
                    display(['Tree 1 Harvest: Beginning picking process.']);
                    for x = 1:(size(app.tree1_pos, 1) + offset )
                        %% HARVEST ONLY
                        % Picking first fruit, Picking only, No QA.
                        if x == 1
                            app.SystemStatus.Text = 'Harvesting fruit from Tree 1.';
                            app.SystemStatus.FontColor = '#FFA500';
                            % Look for Mandarin
                            display(['Tree 1 Harvest: Look for Mandarin ', num2str(x)]);
                            app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.45,app.tree1_pos(x,3)],25,0,false,0,1,app.g1,app.g2,0,app.cow);
                            
                            % Move to initial Mandarin location
                            display(['Tree 1 Harvest: Go to Mandarin ', num2str(x)]);
                            app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.2,app.tree1_pos(x,3)],25,0,false,0,1,app.g1,app.g2,1,app.cow);
                        
                            % Move Mandarins away from tree
                            display(['Tree 1 Harvest: Pick Mandarin ', num2str(x), ' from tree.']);
                            app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.45,app.tree1_pos(x,3)],25,app.tree1_obj{x},true,app.tree1_verts{x},1,app.g1,app.g2,0,app.cow);
                        
                            % Move Mandarins above crate
                            display(['Tree 1 Harvest: Place Mandarin ', num2str(x), ' above crate.']);
                            app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.7],25,app.tree1_obj{x},true,app.tree1_verts{x},3,app.g1,app.g2,0,app.cow);
                         
                            % Place Mandarins in crate
                            display(['Tree 1 Harvest: Place Mandarin ', num2str(x), ' within the crate.']);
                            app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.2],25,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g1,app.g2,0,app.cow);
                        
                            % Lift End Effector from crate
                            display(['Tree 1 Harvest: Lift gripper ', num2str(x), ' from the crate.']);
                            app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.6],25,0,false,0,2,app.g1,app.g2,2,app.cow);
                        
                            % Face left
                            app.robotFunc.MoveRobot(app.modelLinearUR3,[-0.6,0,0.5],30,0,false,0,0,app.g1,app.g2,2,app.cow);
            
                            % Count Mandarin picked.
                            app.fruitPicked = app.fruitPicked + 1;
                            app.fruitCountDisplay.Text = num2str(app.fruitPicked);
                            
                            display(['Tree 1 Harvest: The total number of Mandarins picked from tree 1 is ', num2str(x)]);
                        elseif x <= size(app.tree1_pos, 1)
                            app.SystemStatus.Text = 'Harvesting and sorting fruit from tree 1.';
                            app.SystemStatus.FontColor = '#FFA500';
                            %% HARVEST AND QA
                            % Now that a fruit has been picked, we can begin QA.
                            % Look for Mandarin % Look for Mandarin in unsorted crate
                            display(['Tree 1 Harvest: Look for Mandarin ', num2str(x)]);
                            display(['Quality Control S1: Look for Mandarins to filter ', num2str(x-offset)]);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.45,app.tree1_pos(x,3)],25,0,false,0,1,app.g1,app.g2,0,app.modelPanda,[app.tree1_above_crate(x-offset,1),app.tree1_above_crate(x-offset,2),app.tree1_above_crate(x-offset,3)],0,false,0,2,app.g3,app.g4,0,app.cow);
                            
                            % Move to initial Mandarin location % Go to mandarin in unsorted crate and grip
                            display(['Tree 1 Harvest: Go to Mandarin ', num2str(x)]);
                            display(['Quality Control S1: Go to Mandarin ', num2str(x-offset)]);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.2,app.tree1_pos(x,3)],25,0,false,0,1,app.g1,app.g2,1,app.modelPanda,[app.tree1_crate_pos(x-offset,1),app.tree1_crate_pos(x-offset,2),app.tree1_crate_pos(x-offset,3)+0.2],0,false,0,2,app.g3,app.g4,1,app.cow);
                        
                            % Move Mandarins away from tree  % Pick up mandarin from unsorted crate
                            display(['Tree 1 Harvest: Pick Mandarin ', num2str(x), ' from tree.']);
                            display(['Quality Control S1: Pick Mandarin ', num2str(x-offset), ' from tree.']);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree1_pos(x,1),app.tree1_pos(x,2)-0.45,app.tree1_pos(x,3)],25,app.tree1_obj{x},true,app.tree1_verts{x},1,app.g1,app.g2,0,app.modelPanda,[app.tree1_above_crate(x-offset,1),app.tree1_above_crate(x-offset,2),app.tree1_above_crate(x-offset,3)+0.2],app.tree1_obj{x-offset},true,app.tree1_verts{x-offset},2,app.g3,app.g4,0,app.cow);
                        
                            % Move Mandarins above crate % Move mandarin above sorted crate
                            display(['Tree 1 Harvest: Place Mandarin ', num2str(x), ' above crate.']);
                            display(['Quality Control S1: Place Mandarin ', num2str(x-offset), ' above crate.']);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.7],25,app.tree1_obj{x},true,app.tree1_verts{x},3,app.g1,app.g2,0,app.modelPanda,[app.tree1_above_sorted_crate(x-offset,1),app.tree1_above_sorted_crate(x-offset,2),app.tree1_above_sorted_crate(x-offset,3)+0.5],app.tree1_obj{x-offset},true,app.tree1_verts{x-offset},2,app.g3,app.g4,0,app.cow);
                         
                            % Place Mandarins in crate % Place Mandarins in sorted crate
                            display(['Tree 1 Harvest: Place Mandarin ', num2str(x), ' within the crate.']);
                            display(['Quality Control S1: Place Mandarin ', num2str(x-offset), ' within the crate.']);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.2],25,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g1,app.g2,0,app.modelPanda,[app.tree1_sorted_crate_pos(x-offset,1),app.tree1_sorted_crate_pos(x-offset,2),app.tree1_sorted_crate_pos(x-offset,3)+0.2],app.tree1_obj{x-offset},true,app.tree1_verts{x-offset},2,app.g3,app.g4,2,app.cow);
                        
                            % Lift End Effector from crate % Release mandarin and lift away from sorted crate
                            display(['Tree 1 Harvest: Lift gripper ', num2str(x), ' from the crate.']);
                            display(['Quality Control S1: Lift gripper ', num2str(x-offset), ' from the crate.']);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.6],25,0,false,0,2,app.g1,app.g2,2,app.modelPanda,[app.tree1_above_sorted_crate(x-offset,1),app.tree1_above_sorted_crate(x-offset,2),app.tree1_above_sorted_crate(x-offset,3)+0.5],0,false,0,2,app.g3,app.g4,0,app.cow);
                        
                            % Count Mandarin picked. % Release mandarin and lift away from sorted crate
                            app.fruitPicked = app.fruitPicked + 1;
                            app.fruitCountDisplay.Text = num2str(app.fruitPicked);
        
                            app.fruitSorted = app.fruitSorted + 1;
                            app.sortCountDisplay.Text = num2str(app.fruitSorted);

                            if x <= (size(app.tree1_pos, 1) + offset )
                                app.fruitRipe = app.fruitRipe + 1;
                                app.ripeFruitDisplay.Text = num2str(app.fruitRipe);
                            end
        
                            display(['Tree 1 Harvest: The total number of Mandarins picked from tree 1 is ', num2str(x)]);
                            display(['Quality Control S1: The total number of Mandarins picked from tree 1 is ', num2str(x-offset)]);
        
                            % Face left
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[-0.6,0,0.5],25,0,false,0,0,app.g1,app.g2,2,app.modelPanda,[-1.2,-0.7,1.3],0,false,0,0,app.g3,app.g4,2,app.cow);
        
                        else
                            %% QA Only for remaining fruit
                            app.SystemStatus.Text = 'Sorting the remaining fruit from Tree 1.';
                            app.SystemStatus.FontColor = 'yellow';
                            % Look for Mandarin in unsorted crate
                            display(['Quality Control S1: Look for Mandarins to filter ', num2str(x-offset)]);
                            app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_crate(x-offset,1),app.tree1_above_crate(x-offset,2),app.tree1_above_crate(x-offset,3)],25,0,false,0,2,app.g3,app.g4,0,app.cow);
                            
                            % Go to mandarin in unsorted crate and grip
                            display(['Quality Control S1: Go to Mandarin ', num2str(x-offset)]);
                            app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_crate_pos(x-offset,1),app.tree1_crate_pos(x-offset,2),app.tree1_crate_pos(x-offset,3)+0.2],25,0,false,0,2,app.g3,app.g4,1,app.cow);
                            
                            % Pick up mandarin from unsorted crate
                            display(['Quality Control S1: Pick Mandarin ', num2str(x-offset), ' from tree.']);
                            app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_crate(x-offset,1),app.tree1_above_crate(x-offset,2),app.tree1_above_crate(x-offset,3)+0.2],25,app.tree1_obj{x-offset},true,app.tree1_verts{x-offset},2,app.g3,app.g4,0,app.cow);
                        
                            % Move mandarin above sorted crate
                            display(['Quality Control S1: Place Mandarin ', num2str(x-offset), ' above crate.']);
                            app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_sorted_crate(x-offset,1),app.tree1_above_sorted_crate(x-offset,2),app.tree1_above_sorted_crate(x-offset,3)+0.5],25,app.tree1_obj{x-offset},true,app.tree1_verts{x-offset},2,app.g3,app.g4,0,app.cow);
                         
                            % Place Mandarins in sorted crate
                            display(['Quality Control S1: Place Mandarin ', num2str(x-offset), ' within the crate.']);
                            app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_sorted_crate_pos(x-offset,1),app.tree1_sorted_crate_pos(x-offset,2),app.tree1_sorted_crate_pos(x-offset,3)+0.2],25,app.tree1_obj{x-offset},true,app.tree1_verts{x-offset},2,app.g3,app.g4,2,app.cow);
                        
                            % Release mandarin and lift away from sorted crate
                            display(['Quality Control S1: Lift gripper ', num2str(x-offset), ' from the crate.']);
                            app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_sorted_crate(x-offset,1),app.tree1_above_sorted_crate(x-offset,2),app.tree1_above_sorted_crate(x-offset,3)+0.5],25,0,false,0,2,app.g3,app.g4,0,app.cow);
                       
                            % Count Mandarin picked.
                            display(['Quality Control S1: The total number of Mandarins picked from tree 1 is ', num2str(x-offset)]);
                            app.fruitSorted = app.fruitSorted + 1;
                            app.sortCountDisplay.Text = num2str(app.fruitSorted);
        
                            % Return Robot to beginning.
                            app.robotFunc.MoveRobot(app.modelPanda,[-1.2,-0.7,1.3],25,0,false,0,0,app.g3,app.g4,2,app.cow);
        
                        end
                    end
                end
                display(['Tree 1 Harvest: Completed.']);
                display(['=====================================']);

                %% Reset function for following tree
                app.treeCount = app.treeCount + 1;
                app.sortCount = app.sortCount + 1;
                app.HarvestQAButton.Enable = true;
                app.HarvestOnlyButton.Enable = true;

            elseif app.treeCount == 2
                %% Harvest and QA for TREE 2
                display(['Tree 1 Harvest: Beginning picking process.']);
                for x = 1:(size(app.tree2_pos, 1) + offset )
                    %% HARVEST ONLY
                    % Picking first fruit, Picking only, No QA.
                    if x == 1
                        app.SystemStatus.Text = 'Harvesting fruit from Tree 2.';
                        app.SystemStatus.FontColor = '#FFA500';
                        % Look for Mandarin
                        display(['Tree 2 Harvest: Look for Mandarin ', num2str(x)]);
                        app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_pos(x,1),app.tree2_pos(x,2)-0.45,app.tree2_pos(x,3)],50,0,false,0,1,app.g1,app.g2,0,app.cow);
                    
                        % Move to initial Mandarin location
                        display(['Tree 2 Harvest: Go to Mandarin ', num2str(x)]);
                        app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_pos(x,1),app.tree2_pos(x,2)-0.2,app.tree2_pos(x,3)],30,0,false,0,1,app.g1,app.g2,1,app.cow);
                    
                        % Move Mandarins away from tree
                        display(['Tree 2 Harvest: Pick Mandarin ', num2str(x), ' from tree.']);
                        app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_picked(x,1),app.tree2_picked(x,2)-0.45,app.tree2_picked(x,3)],30,app.tree2_obj{x},true,app.tree2_verts{x},1,app.g1,app.g2,0,app.cow);
                    
                        % Move Mandarins to crate
                        display(['Tree 2 Harvest: Place Mandarin ', num2str(x), ' above crate.']);
                        app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_above_crate(x,1),app.tree2_above_crate(x,2),app.tree2_above_crate(x,3)+0.7],30,app.tree2_obj{x},true,app.tree2_verts{x},2,app.g1,app.g2,3,app.cow);
                    
                        % Place Mandarins in crate
                        display(['Tree 2 Harvest: Place Mandarin ', num2str(x), ' within the crate.']);
                        app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_crate_pos(x,1),app.tree2_crate_pos(x,2),app.tree2_crate_pos(x,3)+0.2],30,app.tree2_obj{x},true,app.tree2_verts{x},2,app.g1,app.g2,0,app.cow);
                    
                        % Lift End Effector from crate
                        display(['Tree 2 Harvest: Lift the gripper ', num2str(x), ' from the crate.']);
                        app.robotFunc.MoveRobot(app.modelLinearUR3,[app.tree2_above_crate(x,1),app.tree2_above_crate(x,2),app.tree2_above_crate(x,3)+0.7],30,0,false,0,2,app.g1,app.g2,2,app.cow);
                    
                        % Count Mandarin picked.
                        app.fruitPicked = app.fruitPicked + 1;
                        app.fruitCountDisplay.Text = num2str(app.fruitPicked);
                        display(['Tree 2 Harvest: The total number of Mandarins picked from tree 2 is ', num2str(x)]);

                    elseif x <= size(app.tree1_pos, 1)

                            %% HARVEST AND QA
                            % Now that a fruit has been picked, we can begin QA.
                            % Look for Mandarin % Look for Mandarin in unsorted crate
                            app.SystemStatus.Text = 'Harvesting and sorting fruit from tree 2.';
                            app.SystemStatus.FontColor = '#FFA500';
                            display(['Tree 2 Harvest: Look for Mandarin ', num2str(x)]);
                            display(['Quality Control S2: Look for Mandarins to filter ', num2str(x-offset)]);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree2_pos(x,1),app.tree2_pos(x,2)-0.45,app.tree2_pos(x,3)],25,0,false,0,1,app.g1,app.g2,0,app.modelPanda,[app.tree2_above_crate(x-offset,1),app.tree2_above_crate(x-offset,2),app.tree2_above_crate(x-offset,3)],0,false,0,2,app.g3,app.g4,0,app.cow);
                            
                            % Move to initial Mandarin location % Go to mandarin in unsorted crate and grip
                            display(['Tree 2 Harvest: Go to Mandarin ', num2str(x)]);
                            display(['Quality Control S2: Go to Mandarin ', num2str(x-offset)]);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree2_pos(x,1),app.tree2_pos(x,2)-0.2,app.tree2_pos(x,3)],25,0,false,0,1,app.g1,app.g2,1,app.modelPanda,[app.tree2_crate_pos(x-offset,1),app.tree2_crate_pos(x-offset,2),app.tree2_crate_pos(x-offset,3)+0.2],0,false,0,2,app.g3,app.g4,1,app.cow);
                        
                            % Move Mandarins away from tree  % Pick up mandarin from unsorted crate
                            display(['Tree 2 Harvest: Pick Mandarin ', num2str(x), ' from tree.']);
                            display(['Quality Control S2: Pick Mandarin ', num2str(x-offset), ' from tree.']);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree2_pos(x,1),app.tree2_pos(x,2)-0.45,app.tree2_pos(x,3)],25,app.tree2_obj{x},true,app.tree2_verts{x},1,app.g1,app.g2,0,app.modelPanda,[app.tree2_above_crate(x-offset,1),app.tree2_above_crate(x-offset,2),app.tree2_above_crate(x-offset,3)+0.2],app.tree2_obj{x-offset},true,app.tree2_verts{x-offset},2,app.g3,app.g4,0,app.cow);
                        
                            % Move Mandarins above crate % Move mandarin above sorted crate
                            display(['Tree 2 Harvest: Place Mandarin ', num2str(x), ' above crate.']);
                            display(['Quality Control S2: Place Mandarin ', num2str(x-offset), ' above crate.']);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree2_crate_pos(x,1),app.tree2_crate_pos(x,2),app.tree2_crate_pos(x,3)+0.7],25,app.tree2_obj{x},true,app.tree2_verts{x},3,app.g1,app.g2,0,app.modelPanda,[app.tree2_above_sorted_crate(x-offset,1),app.tree2_above_sorted_crate(x-offset,2),app.tree2_above_sorted_crate(x-offset,3)+0.5],app.tree2_obj{x-offset},true,app.tree2_verts{x-offset},2,app.g3,app.g4,0,app.cow);
                         
                            % Place Mandarins in crate % Place Mandarins in sorted crate
                            display(['Tree 2 Harvest: Place Mandarin ', num2str(x), ' within the crate.']);
                            display(['Quality Control S2: Place Mandarin ', num2str(x-offset), ' within the crate.']);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree2_crate_pos(x,1),app.tree2_crate_pos(x,2),app.tree2_crate_pos(x,3)+0.2],25,app.tree2_obj{x},true,app.tree2_verts{x},2,app.g1,app.g2,0,app.modelPanda,[app.tree2_sorted_crate_pos(x-offset,1),app.tree2_sorted_crate_pos(x-offset,2),app.tree2_sorted_crate_pos(x-offset,3)+0.2],app.tree2_obj{x-offset},true,app.tree2_verts{x-offset},2,app.g3,app.g4,2,app.cow);

                            % Lift End Effector from crate % Release mandarin and lift away from sorted crate
                            display(['Tree 2 Harvest: Lift gripper ', num2str(x), ' from the crate.']);
                            display(['Quality Control S2: Lift gripper ', num2str(x-offset), ' from the crate.']);
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[app.tree2_crate_pos(x,1),app.tree2_crate_pos(x,2),app.tree2_crate_pos(x,3)+0.6],25,0,false,0,2,app.g1,app.g2,2,app.modelPanda,[app.tree2_above_sorted_crate(x-offset,1),app.tree2_above_sorted_crate(x-offset,2),app.tree2_above_sorted_crate(x-offset,3)+0.5],0,false,0,2,app.g3,app.g4,0,app.cow);
                        
                            % Count Mandarin picked. % Release mandarin and lift away from sorted crate
                            app.fruitPicked = app.fruitPicked + 1;
                            app.fruitCountDisplay.Text = num2str(app.fruitPicked);
        
                            app.fruitSorted = app.fruitSorted + 1;
                            app.sortCountDisplay.Text = num2str(app.fruitSorted);

                            if x <= (size(app.tree2_pos, 1) + offset )
                                app.fruitRipe = app.fruitRipe + 1;
                                app.ripeFruitDisplay.Text = num2str(app.fruitRipe);
                            end
        
                            display(['Tree 2 Harvest: The total number of Mandarins picked from tree 2 is ', num2str(x)]);
                            display(['Quality Control S2: The total number of Mandarins picked from tree 2 is ', num2str(x-offset)]);
        
                            % Face left
                            app.robotFunc.MoveTwoRobots(app.modelLinearUR3,[-0.6,0,0.5],25,0,false,0,0,app.g1,app.g2,2,app.modelPanda,[-1.2,-0.7,1.3],0,false,0,0,app.g3,app.g4,2,app.cow);

                    else
                        %% QA Only for remaining fruit
                        % Look for Mandarin in unsorted crate
                        app.SystemStatus.Text = 'Sorting the remaining fruit from tree 2.';
                        app.SystemStatus.FontColor = 'yellow';
                        display(['Quality Control S2: Look for Mandarins to filter ', num2str(x-offset)]);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_crate(x-offset,1),app.tree2_above_crate(x-offset,2),app.tree2_above_crate(x-offset,3)],50,0,false,0,2,app.g3,app.g4,0,app.cow);
                        
                        % Go to mandarin in unsorted crate and grip
                        display(['Quality Control S2: Go to Mandarin ', num2str(x-offset)]);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_crate_pos(x-offset,1),app.tree2_crate_pos(x-offset,2),app.tree2_crate_pos(x-offset,3)+0.2],50,0,false,0,2,app.g3,app.g4,1,app.cow);
                        
                        % Pick up mandarin from unsorted crate
                        display(['Quality Control S2: Pick Mandarin ', num2str(x-offset), ' from tree.']);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_crate(x-offset,1),app.tree2_above_crate(x-offset,2),app.tree2_above_crate(x-offset,3)+0.2],50,app.tree2_obj{x-offset},true,app.tree2_verts{x-offset},2,app.g3,app.g4,0,app.cow);
                    
                        % Move mandarin above sorted crate
                        display(['Quality Control S2: Place Mandarin ', num2str(x-offset), ' above crate.']);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_sorted_crate(x-offset,1),app.tree2_above_sorted_crate(x-offset,2),app.tree2_above_sorted_crate(x-offset,3)+0.5],50,app.tree2_obj{x-offset},true,app.tree2_verts{x-offset},2,app.g3,app.g4,0,app.cow);
                     
                        % Place Mandarins in sorted crate
                        display(['Quality Control S2: Place Mandarin ', num2str(x-offset), ' within the crate.']);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_sorted_crate_pos(x-offset,1),app.tree2_sorted_crate_pos(x-offset,2),app.tree2_sorted_crate_pos(x-offset,3)+0.2],50,app.tree2_obj{x-offset},true,app.tree2_verts{x-offset},2,app.g3,app.g4,2,app.cow);
                    
                        % Release mandarin and lift away from sorted crate
                        display(['Quality Control S2: Lift gripper ', num2str(x-offset), ' from the crate.']);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree2_above_sorted_crate(x-offset,1),app.tree2_above_sorted_crate(x-offset,2),app.tree2_above_sorted_crate(x-offset,3)+0.5],50,0,false,0,2,app.g3,app.g4,0,app.cow);
                   
                        % Count Mandarin picked.
                        display(['Quality Control S2: The total number of Mandarins picked from tree 2 is ', num2str(x-offset)]);
                        app.fruitSorted = app.fruitSorted + 1;
                        app.sortCountDisplay.Text = num2str(app.fruitSorted);
    
                        % Return Robot to beginning.
                        app.robotFunc.MoveRobot(app.modelPanda,[-1.2,-0.7,1.3],40,0,false,0,0,app.g3,app.g4,2,app.cow);
                    end
                end
                        %% Reset function for following tree
                        app.treeCount = app.treeCount + 1;
                        app.sortCount = app.sortCount + 1;
                        app.HarvestQAButton.Enable = true;
                        app.HarvestOnlyButton.Enable = true;
            else
                if app.treeCount == 2 && app.sortCount == 1
                    app.SystemStatus.Text = 'Sorting the remaining fruit.';
                    app.SystemStatus.FontColor = 'yellow';
                    for x = 1:size(app.tree1_sorted_crate_pos, 1)
                        % Look for Mandarin in unsorted crate
                        display(['Quality Control S1: Look for Mandarins to filter ', num2str(x)]);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_crate(x,1),app.tree1_above_crate(x,2),app.tree1_above_crate(x,3)],50,0,false,0,2,app.g3,app.g4,0,app.cow);
                        
                        % Go to mandarin in unsorted crate and grip
                        display(['Quality Control S1: Go to Mandarin ', num2str(x)]);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_crate_pos(x,1),app.tree1_crate_pos(x,2),app.tree1_crate_pos(x,3)+0.2],50,0,false,0,2,app.g3,app.g4,1,app.cow);
                        
                        % Pick up mandarin from unsorted crate
                        display(['Quality Control S1: Pick Mandarin ', num2str(x), ' from tree.']);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_crate(x,1),app.tree1_above_crate(x,2),app.tree1_above_crate(x,3)+0.2],50,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g3,app.g4,0,app.cow);
                    
                        % Move mandarin above sorted crate
                        display(['Quality Control S1: Place Mandarin ', num2str(x), ' above crate.']);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_sorted_crate(x,1),app.tree1_above_sorted_crate(x,2),app.tree1_above_sorted_crate(x,3)+0.5],50,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g3,app.g4,0,app.cow);
                     
                        % Place Mandarins in sorted crate
                        display(['Quality Control S1: Place Mandarin ', num2str(x), ' within the crate.']);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_sorted_crate_pos(x,1),app.tree1_sorted_crate_pos(x,2),app.tree1_sorted_crate_pos(x,3)+0.2],50,app.tree1_obj{x},true,app.tree1_verts{x},2,app.g3,app.g4,2,app.cow);
                    
                        % Release mandarin and lift away from sorted crate
                        display(['Quality Control S1: Lift gripper ', num2str(x), ' from the crate.']);
                        app.robotFunc.MoveRobot(app.modelPanda,[app.tree1_above_sorted_crate(x,1),app.tree1_above_sorted_crate(x,2),app.tree1_above_sorted_crate(x,3)+0.5],50,0,false,0,2,app.g3,app.g4,0,app.cow);
                    
                        % Count Mandarin picked.
                        display(['Quality Control S1: The total number of Mandarins picked from tree 1 is ', num2str(x)]);
                        app.fruitSorted = app.fruitSorted + 1;
                        app.sortCountDisplay.Text = num2str(app.fruitSorted);

                        if x < size(app.tree1_sorted_crate_pos, 1)
                            app.fruitRipe = app.fruitRipe + 1;
                            app.ripeFruitDisplay.Text = num2str(app.fruitRipe);
                        end
                    end
                display(['Quality Control S1: Completed Filtering.']);
                display(['=====================================']);
                end
                
                display(['NO MORE TREES TO HARVEST.']);
                app.HarvestOnlyButton.Enable = false;
                app.HarvestQAButton.Enable = false;
                app.SystemStatus.Text = 'No more fruit to harvest.';
                app.SystemStatus.FontColor = 'black';
            end
                app.automode = 0;
        end

        % Button pushed function: STOPButton
        function STOPButtonValueChanged(app, event)
           % app.estop = app.STOPButton.Value;

      
            disp ('eStop activated');
                app.estop = true;
                StoreSwitchButtons.setgeteStop(true);

            % Update System Status
            app.SystemStatus.Text = 'EMERGENCY STOP PRESSED.';
            app.SystemStatus.FontColor = 'red';

            % Disable stop button.
            app.STOPButton.Enable = false;

            % Turn on safety switch and enable switch for user to turn off.
            app.SafetySwitch.Enable = true;
            app.SafetySwitch.Value = 'On';
            app.SafetySwitch.FontColor = 'Magenta';

            % Switch off manual mode
            app.ManualModeSwitch.Value = 'Off';
            app.ManualModeSwitch.Enable = false;
            app.RobotToggleSwitch.Enable = false;
            app.q1Slider.Enable = false;
            app.q2Slider.Enable = false;
            app.q3Slider.Enable = false;
            app.q4Slider.Enable = false;
            app.q5Slider.Enable = false;
            app.q6Slider.Enable = false;
            app.q7Slider.Enable = false;
            app.g1Slider.Enable = false;


        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            
            if app.teachresume == 1;

                %% insert funtion to move from currentpos back to pre teach pos for both robots
                manualTwoRobotAnimation(app,app.modelLinearUR3,app.modelPanda)
              
                app.teachresume = 0;

            else
            end

            % If safety switch turned off
            if app.safety == 0
                %app.resume = app.RESUMEButton.Value;
                app.SystemStatus.Text = 'Resuming program.';

                
                app.SystemStatus.FontColor = '#276221';
                %Turn off eStop and Global value update
                app.estop = false;
                StoreSwitchButtons.setgeteStop(false);
    
                app.STOPButton.Enable = true;
                app.SafetySwitch.Enable = false;
                app.RESUMEButton.Enable = false;
                app.ManualModeSwitch.Enable = false;
            else
                app.SystemStatus.Text = 'TURN OFF SAFETY TO RESUME';
                app.SystemStatus.FontColor = 'Magenta';
            end
            
            % global resume;
            % resume = true;

        end

        % Value changed function: SafetySwitch
        function SafetySwitchValueChanged(app, event)
                
             if app.automode == 1
            
                if strcmp(app.SafetySwitch.Value, 'Off')
                    app.safety = false;
                    disp('safety off')
                    app.SystemStatus.Text = 'Safety switched off';
                    app.SystemStatus.FontColor = 'Magenta';
                    
                    % if app.manualMode
                    %     app.RESUMEButton.Enable = false;
                    %     app.ManualModeSwitch.Enable = true;
                    % else
                        app.RESUMEButton.Enable = true;
                        app.ManualModeSwitch.Enable = true;
                    % end

                elseif strcmp(app.SafetySwitch.Value, 'On')
                    app.safety = true;
                    disp('safety on')
                    app.SystemStatus.Text = 'Safety switched On';
                    app.SystemStatus.FontColor = 'Red';
                    app.RESUMEButton.Enable = false;
                    app.ManualModeSwitch.Enable = false;
                 end
            
             elseif app.automode == 0
                    
                 if strcmp(app.SafetySwitch.Value, 'Off')
                    app.safety = false;
                    disp('safety off')
                    app.SystemStatus.Text = 'Safety switched off';
                    app.SystemStatus.FontColor = 'Magenta';
                    
                    app.RESUMEButton.Enable = false;
                    app.ManualModeSwitch.Enable = true;
                    
                elseif strcmp(app.SafetySwitch.Value, 'On')
                    app.safety = true;
                    disp('safety on')
                    app.SystemStatus.Text = 'Safety switched On';
                    app.SystemStatus.FontColor = 'Red';
                    app.RESUMEButton.Enable = false;
                    app.ManualModeSwitch.Enable = false;
                 end
            end
        end

        % Value changed function: LightCurtainSwitch
        function LightCurtainSwitchValueChanged(app, event)
            app.SystemStatus.Text = 'Light Curtain switched on.';
            app.SystemStatus.FontColor = 'yellow';
            % Create first light curtain
	        app.SystemStatus.Text = 'Light Curtain switched on.';
            app.SystemStatus.FontColor = 'yellow';

            set(0, 'DefaultFigureWindowStyle', 'docked')
        
            % Define parameters
            x_start = 1; % Starting X coordinate
            y_range = -1.8:0.1:0.7; % Range for Y
            z_range = 0.3:0.1:1; % Range for Z
            
            % Create a flat square mesh along the X-axis starting at x = 1
            [Y, Z] = meshgrid(y_range, z_range);
            X = x_start * ones(size(Y)); % Set X to x_start for a flat surface
            mesh_h = mesh(X, Y, Z);
            
            % Set the transparency (alpha) for the meshgrid
            alpha(mesh_h, 0.5);
		
            % Create second light curtain

            set(0, 'DefaultFigureWindowStyle', 'docked')
        
            % Define parameters
            x_start = -1.7; % Starting X coordinate
            y_range = -1.8:0.1:0.7; % Range for Y
            z_range = 0.3:0.1:1; % Range for Z
            
            % Create a flat square mesh along the X-axis starting at x = 1
            [Y, Z] = meshgrid(y_range, z_range);
            X = x_start * ones(size(Y)); % Set X to x_start for a flat surface
            mesh_h = mesh(X, Y, Z);
            
            % Set the transparency (alpha) for the meshgrid
            alpha(mesh_h, 0.5);

	        app.LightCurtainSwitch.Enable = false;

        end

        % Value changed function: ManualModeSwitch
        function ManualModeSwitchValueChanged(app, event)


            if app.automode == 1

            if strcmp(app.ManualModeSwitch.Value, 'Off')
                    StoreSwitchButtons.setgetManual(false);
                    
                    % Manual mode toggle for safety
                    app.manualMode = false;

                    % Disable Emergency Stop
                    app.STOPButton.Enable = false;  
                    app.RESUMEButton.Enable = true;

                    app.teachresume = 1; % use to trigger need for qpath function before resume
                    
                    % Enable autonomous mode
                    app.HarvestQAButton.Enable = false;
                    app.HarvestOnlyButton.Enable = false;

                    % Disable sliders
                    app.RobotToggleSwitch.Enable = false;
                    app.q1Slider.Enable = false;
                    app.q2Slider.Enable = false;
                    app.q3Slider.Enable = false;
                    app.q4Slider.Enable = false;
                    app.q5Slider.Enable = false;
                    app.q6Slider.Enable = false;
                    app.q7Slider.Enable = false;
                    app.g1Slider.Enable = false;

               elseif strcmp(app.ManualModeSwitch.Value, 'On')
                   
                   %take robot positions before teaching
                   app.lastQPanda = app.modelPanda.model.getpos();
                   app.lastQUR3 = app.modelLinearUR3.model.getpos();

                   app.manualMode = true;

                    % Switch on manual control
                    StoreSwitchButtons.setgetManual(true);
                    app.SystemStatus.Text = 'Manual mode switched on.';
                    app.SystemStatus.FontColor = 'blue';
        
                    % Disable auto harvest.
                    app.HarvestOnlyButton.Enable = false;
                    app.HarvestQAButton.Enable = false;
                    app.SafetySwitch.Enable = false;
                    app.RESUMEButton.Enable = false;
        
                    % Enable Emergency Stop
                    app.STOPButton.Enable = true;  
        
                    % Enable sliders
                    app.RobotToggleSwitch.Enable = true;
                    app.q1Slider.Enable = true;
                    app.q2Slider.Enable = true;
                    app.q3Slider.Enable = true;
                    app.q4Slider.Enable = true;
                    app.q5Slider.Enable = true;
                    app.q6Slider.Enable = true;
                    app.q7Slider.Enable = true;
                    app.g1Slider.Enable = true;
            end

            elseif app.automode == 0
               % Switch off manual control
               if strcmp(app.ManualModeSwitch.Value, 'Off')
                    StoreSwitchButtons.setgetManual(false);
                    
                    % Manual mode toggle for safety
                    app.manualMode = false;

                    % Disable Emergency Stop
                    app.STOPButton.Enable = false;  
                    app.RESUMEButton.Enable = false;
                    
                    % Enable autonomous mode
                    app.HarvestQAButton.Enable = true;
                    app.HarvestOnlyButton.Enable = true;

                    % Disable sliders
                    app.RobotToggleSwitch.Enable = false;
                    app.q1Slider.Enable = false;
                    app.q2Slider.Enable = false;
                    app.q3Slider.Enable = false;
                    app.q4Slider.Enable = false;
                    app.q5Slider.Enable = false;
                    app.q6Slider.Enable = false;
                    app.q7Slider.Enable = false;
                    app.g1Slider.Enable = false;

               elseif strcmp(app.ManualModeSwitch.Value, 'On')
                   
                   app.manualMode = true;

                    % Switch on manual control
                    StoreSwitchButtons.setgetManual(true);
                    app.SystemStatus.Text = 'Manual mode switched on.';
                    app.SystemStatus.FontColor = 'blue';
        
                    % Disable auto harvest.
                    app.HarvestOnlyButton.Enable = false;
                    app.HarvestQAButton.Enable = false;
                    app.SafetySwitch.Enable = false;
                    app.RESUMEButton.Enable = false;
        
                    % Enable Emergency Stop
                    app.STOPButton.Enable = true;  
        
                    % Enable sliders
                    app.RobotToggleSwitch.Enable = true;
                    app.q1Slider.Enable = true;
                    app.q2Slider.Enable = true;
                    app.q3Slider.Enable = true;
                    app.q4Slider.Enable = true;
                    app.q5Slider.Enable = true;
                    app.q6Slider.Enable = true;
                    app.q7Slider.Enable = true;
                    app.g1Slider.Enable = true;
               end
            end
            
        end

        % Value changed function: RobotToggleSwitch
        function RobotToggleSwitchValueChanged(app, event)
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                app.SystemStatus.Text = 'Manual Mode: Harvest.';
                app.SystemStatus.FontColor = 'magenta';
                
                % Update link limits
                app.q1Slider.Limits = [-0.8 -0.01];
                app.q2Slider.Limits = [-360 360]*pi/180;
                app.q3Slider.Limits = [-90 90]*pi/180;
                app.q4Slider.Limits = [-170 170]*pi/180;
                app.q5Slider.Limits = [-360 360]*pi/180;
                app.q6Slider.Limits = [-360 360]*pi/180;
                app.q7Slider.Limits = [-360 360]*pi/180;
                app.g1Slider.Limits = [-40, 20];
                % .Limits = [-pi, pi];

                % Update slider value to match link value
                q = app.modelLinearUR3.model.getpos()
                app.q1Slider.Value = min(max(q(1, 1), app.q1Slider.Limits(1)), app.q1Slider.Limits(2));
                app.q2Slider.Value = min(max(q(1, 2), app.q2Slider.Limits(1)), app.q2Slider.Limits(2));
                app.q3Slider.Value = min(max(q(1, 3), app.q3Slider.Limits(1)), app.q3Slider.Limits(2));
                app.q4Slider.Value = min(max(q(1, 4), app.q4Slider.Limits(1)), app.q4Slider.Limits(2));
                app.q5Slider.Value = min(max(q(1, 5), app.q5Slider.Limits(1)), app.q5Slider.Limits(2));
                app.q6Slider.Value = min(max(q(1, 6), app.q6Slider.Limits(1)), app.q6Slider.Limits(2));
                app.q7Slider.Value = min(max(q(1, 7), app.q7Slider.Limits(1)), app.q7Slider.Limits(2));

            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                app.SystemStatus.Text = 'Manual Mode: QA.';
                app.SystemStatus.FontColor = 'Blue';
                
                % Update link limits
                app.q1Slider.Limits = [-pi, pi];
                app.q2Slider.Limits = [-2.26893, 2.07694];
                app.q3Slider.Limits = [-pi, pi];
                app.q4Slider.Limits = [-2.1293, 2.0944];
                app.q5Slider.Limits = [-pi, pi];
                app.q6Slider.Limits = [-pi, pi];
                app.q7Slider.Limits = [-pi, pi];
                app.g1Slider.Limits = [-40, 20];

                % Update slider value to match link value
                q = app.modelPanda.model.getpos();
                app.q1Slider.Value = min(max(q(1, 1), app.q1Slider.Limits(1)), app.q1Slider.Limits(2));
                app.q2Slider.Value = min(max(q(1, 2), app.q2Slider.Limits(1)), app.q2Slider.Limits(2));
                app.q3Slider.Value = min(max(q(1, 3), app.q3Slider.Limits(1)), app.q3Slider.Limits(2));
                app.q4Slider.Value = min(max(q(1, 5), app.q4Slider.Limits(1)), app.q4Slider.Limits(2));
                app.q5Slider.Value = min(max(q(1, 7), app.q5Slider.Limits(1)), app.q5Slider.Limits(2));
                app.q6Slider.Value = min(max(q(1, 9), app.q6Slider.Limits(1)), app.q6Slider.Limits(2));
                app.q7Slider.Value = min(max(q(1, 11), app.q7Slider.Limits(1)), app.q7Slider.Limits(2));            

            end
        end

        % Value changed function: RESETButton
        function RESETButtonValueChanged(app, event)
            % Delete current App
            delete(app);
            % Create a new instance of the app
            app = FruitLoop();
            % newApp.; % Show the new app instance
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            app.SystemStatus.Text = 'Moving Link 1';
            changingValue = event.Value;
            % Change robot depending on robot toggle.
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                robot = app.modelLinearUR3;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                robot = app.modelPanda;
            end

            % Update link value.
            newQ = robot.model.getpos();
            newQ(1) = changingValue;

            % Animate live.
            manualAnimation(app,robot,newQ)

            % Update End Effector Position on UI
            T = robot.model.fkine(newQ).T;
            app.EndEffX.Text = num2str(T(1,4));
            app.EndEffY.Text = num2str(T(2,4));
            app.EndEffZ.Text = num2str(T(3,4));

        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            app.SystemStatus.Text = 'Moving Link 2';
            changingValue = event.Value;
            % Change robot depending on robot toggle.
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                robot = app.modelLinearUR3;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                robot = app.modelPanda;
            end

            % Update link value.
            newQ = robot.model.getpos();
            newQ(2) = changingValue;

            % Animate live.
            manualAnimation(app,robot,newQ)

            T = robot.model.fkine(newQ).T;
            app.EndEffX.Text = num2str(T(1,4));
            app.EndEffY.Text = num2str(T(2,4));
            app.EndEffZ.Text = num2str(T(3,4));

        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            app.SystemStatus.Text = 'Moving Link 3';
            changingValue = event.Value;
            % Change robot depending on robot toggle.
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                robot = app.modelLinearUR3;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                robot = app.modelPanda;
            end

            % Update link value.
            newQ = robot.model.getpos();
            newQ(3) = changingValue;

            % Animate live.
            manualAnimation(app,robot,newQ)

            T = robot.model.fkine(newQ).T;
            app.EndEffX.Text = num2str(T(1,4));
            app.EndEffY.Text = num2str(T(2,4));
            app.EndEffZ.Text = num2str(T(3,4));

        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            app.SystemStatus.Text = 'Moving Link 4';
            changingValue = event.Value;
            % Change robot depending on robot toggle.
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                robot = app.modelLinearUR3;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                robot = app.modelPanda;
            end

            % Update link value.
            newQ = robot.model.getpos();
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                newQ(4) = changingValue;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                newQ(5) = changingValue;
            end

            % Animate live.
            manualAnimation(app,robot,newQ)

            T = robot.model.fkine(newQ).T;
            app.EndEffX.Text = num2str(T(1,4));
            app.EndEffY.Text = num2str(T(2,4));
            app.EndEffZ.Text = num2str(T(3,4));
            
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            app.SystemStatus.Text = 'Moving Link 5';
            changingValue = event.Value;
            % Change robot depending on robot toggle.
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                robot = app.modelLinearUR3;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                robot = app.modelPanda;
            end

            % Update link value.
            newQ = robot.model.getpos();
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                newQ(5) = changingValue;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                newQ(7) = changingValue;
            end

            % Animate live.
            manualAnimation(app,robot,newQ)

            T = robot.model.fkine(newQ).T;
            app.EndEffX.Text = num2str(T(1,4));
            app.EndEffY.Text = num2str(T(2,4));
            app.EndEffZ.Text = num2str(T(3,4));
            
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
            app.SystemStatus.Text = 'Moving Link 6';
            changingValue = event.Value;
            % Change robot depending on robot toggle.
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                robot = app.modelLinearUR3;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                robot = app.modelPanda;
            end

            % Update link value.
            newQ = robot.model.getpos();
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                newQ(6) = changingValue;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                newQ(9) = changingValue;
            end

            % Animate live.
            manualAnimation(app,robot,newQ)

            T = robot.model.fkine(newQ).T;
            app.EndEffX.Text = num2str(T(1,4));
            app.EndEffY.Text = num2str(T(2,4));
            app.EndEffZ.Text = num2str(T(3,4));
            
        end

        % Value changing function: q7Slider
        function q7SliderValueChanging(app, event)
            app.SystemStatus.Text = 'Moving Link 7';
            changingValue = event.Value;
            % Change robot depending on robot toggle.
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                robot = app.modelLinearUR3;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                robot = app.modelPanda;
            end

            % Update link value.
            newQ = robot.model.getpos();
            if strcmp(app.RobotToggleSwitch.Value, 'Harvest')
                newQ(7) = changingValue;
            elseif strcmp(app.RobotToggleSwitch.Value, 'QA')
                newQ(11) = changingValue;
            end

            % Animate live.
            manualAnimation(app,robot,newQ)

            T = robot.model.fkine(newQ).T;
            app.EndEffX.Text = num2str(T(1,4));
            app.EndEffY.Text = num2str(T(2,4));
            app.EndEffZ.Text = num2str(T(3,4));
            
        end

        % Value changing function: g1Slider
        function g1SliderValueChanging(app, event)
            app.SystemStatus.Text = 'Adjusting Gripper';
            % Animate live for gripper.
            gripperAnimation(app,event.Value)
            
        end

        % Button pushed function: SIMULATECOWButton
        function SIMULATECOWButtonValueChanged(app, event)
            %app.robotFunc.MoveRobot(app.cow,[1,0,0.01],50,0,false,0,1,0,0,0)
            app.cow.model.base = transl(1.5, -.5, 0.01);
            app.cow.model.animate(app.cow.model.getpos());
            drawnow;
            pause(.4);
            StoreSwitchButtons.setgetCow(true);
            
            disp('warning... someone left the gate open!! ')

            hold on;
        end

        % Key press function: figure1
        function figure1KeyPress(app, event)
            key = event.Key;

            switch key
            case 's'
                app.STOPButtonValueChanged(app);
                disp ('s pressed')
                StoreSwitchButtons.setgeteStop(true);

                app.Message.Value = key; % return key value
            case 'o'
            app.SafetySwitchValueChanged(app);
            app.Message.Value = key; % return key value
            case 'r'
                app.RESUMEButton(app);
                app.Message.Value = key;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1KeyPress, true);
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.HarvestOnlyButton = uibutton(app.figure1, 'push');
            app.HarvestOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @HarvestOnlyButtonPushed, true);
            app.HarvestOnlyButton.Text = 'Harvest Only';
            app.QAOnlyButton = uibutton(app.figure1, 'push');
            app.QAOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @QAOnlyButtonPushed, true);
            app.QAOnlyButton.Text = 'QA Only';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'FruitLoopLogo.png');
            app.HarvestQAButton = uibutton(app.figure1, 'push');
            app.HarvestQAButton.ButtonPushedFcn = createCallbackFcn(app, @HarvestQAButtonPushed, true);
            app.HarvestQAButton.Text = 'Harvest & QA';
            app.TemperatureCGaugeLabel = uilabel(app.figure1);
            app.TemperatureCGaugeLabel.Text = 'Temperature (°C)';
            app.TemperatureCGauge = uigauge(app.figure1, 'semicircular');
            app.TemperatureCGauge.Limits = [0 40];
            app.TemperatureCGauge.Value = 23;
            app.HumidityGaugeLabel = uilabel(app.figure1);
            app.HumidityGaugeLabel.Text = 'Humidity (%)';
            app.HumidityGauge = uigauge(app.figure1, 'semicircular');
            app.HumidityGauge.Value = 58;
            app.q2SliderLabel = uilabel(app.figure1);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.figure1);
            app.q2Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q1SliderLabel = uilabel(app.figure1);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.figure1);
            app.q1Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q3SliderLabel = uilabel(app.figure1);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.figure1);
            app.q3Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q5SliderLabel = uilabel(app.figure1);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.figure1);
            app.q5Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q4SliderLabel = uilabel(app.figure1);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.figure1);
            app.q4Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q6SliderLabel = uilabel(app.figure1);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.figure1);
            app.q6Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            app.q7SliderLabel = uilabel(app.figure1);
            app.q7SliderLabel.Text = 'q7';
            app.q7Slider = uislider(app.figure1);
            app.q7Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q7Slider.ValueChangingFcn = createCallbackFcn(app, @q7SliderValueChanging, true);
            app.g1Slider = uislider(app.figure1);
            app.g1Slider.ValueChangingFcn = createCallbackFcn(app, @g1SliderValueChanging, true);
            app.RobotLinksLabel = uilabel(app.figure1);
            app.RobotLinksLabel.Text = 'Robot Links';
            app.GripperLabel = uilabel(app.figure1);
            app.GripperLabel.Text = 'Gripper';
            app.RobotToggleSwitchLabel = uilabel(app.figure1);
            app.RobotToggleSwitchLabel.Text = 'Robot Toggle';
            app.RobotToggleSwitch = uiswitch(app.figure1, 'slider');
            app.RobotToggleSwitch.Items = {'Harvest', 'QA'};
            app.RobotToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @RobotToggleSwitchValueChanged, true);
            app.RobotToggleSwitch.Value = 'Harvest';
            app.ManualModeSwitchLabel = uilabel(app.figure1);
            app.ManualModeSwitchLabel.Text = 'Manual Mode';
            app.ManualModeSwitch = uiswitch(app.figure1, 'slider');
            app.ManualModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualModeSwitchValueChanged, true);
            app.FruitLoopVirtualViewLabel = uilabel(app.figure1);
            app.FruitLoopVirtualViewLabel.Text = 'Fruit Loop Virtual View';
            app.EndEffPositionLabel = uilabel(app.figure1);
            app.EndEffPositionLabel.Text = 'End Eff Position';
            app.CollisionWarningLampLabel = uilabel(app.figure1);
            app.CollisionWarningLampLabel.Text = 'Collision Warning';
            app.CollisionWarningLamp = uilamp(app.figure1);
            app.SafetySwitchLabel = uilabel(app.figure1);
            app.SafetySwitchLabel.Text = {'Safety'; 'Switch'};
            app.SafetySwitch = uiswitch(app.figure1, 'toggle');
            app.SafetySwitch.ValueChangedFcn = createCallbackFcn(app, @SafetySwitchValueChanged, true);
            app.fruitCountDisplay = uilabel(app.figure1);
            app.fruitCountDisplay.Text = '0';
            app.FruitpickedLabel = uilabel(app.figure1);
            app.FruitpickedLabel.Text = 'Fruit picked:';
            app.sortCountDisplay = uilabel(app.figure1);
            app.sortCountDisplay.Text = '0';
            app.FruitsortedLabel = uilabel(app.figure1);
            app.FruitsortedLabel.Text = 'Fruit sorted:';
            app.ripeFruitDisplay = uilabel(app.figure1);
            app.ripeFruitDisplay.Text = '0';
            app.RipeFruitLabel = uilabel(app.figure1);
            app.RipeFruitLabel.Text = 'Ripe Fruit:';
            app.EndEffX = uilabel(app.figure1);
            app.EndEffX.Text = '0';
            app.XLabel = uilabel(app.figure1);
            app.XLabel.Text = 'X:';
            app.EndEffY = uilabel(app.figure1);
            app.EndEffY.Text = '0';
            app.YLabel = uilabel(app.figure1);
            app.YLabel.Text = 'Y:';
            app.EndEffZ = uilabel(app.figure1);
            app.EndEffZ.Text = '0';
            app.ZLabel = uilabel(app.figure1);
            app.ZLabel.Text = 'Z:';
            app.LightCurtainSwitchLabel = uilabel(app.figure1);
            app.LightCurtainSwitchLabel.Text = 'Light Curtain';
            app.LightCurtainSwitch = uiswitch(app.figure1, 'slider');
            app.LightCurtainSwitch.ValueChangedFcn = createCallbackFcn(app, @LightCurtainSwitchValueChanged, true);
            app.HarvestOverviewLabel = uilabel(app.figure1);
            app.HarvestOverviewLabel.Text = 'Harvest Overview';
            app.FruitLoopStatusLabel = uilabel(app.figure1);
            app.FruitLoopStatusLabel.Text = 'Fruit Loop Status:';
            app.SystemStatus = uilabel(app.figure1);
            app.SystemStatus.Text = 'Initialising';
            app.RESETButton = uibutton(app.figure1, 'state');
            app.RESETButton.ValueChangedFcn = createCallbackFcn(app, @RESETButtonValueChanged, true);
            app.RESETButton.Text = 'RESET';
            app.CloseLabel = uilabel(app.figure1);
            app.CloseLabel.Text = 'Close';
            app.OpenLabel = uilabel(app.figure1);
            app.OpenLabel.Text = 'Open';
            app.Image2 = uiimage(app.figure1);
            app.Image2.ImageSource = 'C:\Users\denni\Documents\MATLAB\Industrial Robotics\IndustrialRobotics\Assignment 2\Apps\cow.JPG';
            app.STOPButton = uibutton(app.figure1, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            app.RESUMEButton = uibutton(app.figure1, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.SIMULATECOWButton = uibutton(app.figure1, 'push');
            app.SIMULATECOWButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULATECOWButtonValueChanged, true);
            app.SIMULATECOWButton.Text = 'SIMULATE COW';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FruitLoop(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end